classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ForOrthgonalArmLabel        matlab.ui.control.Label
        TomatoHarvestingRobotLabel  matlab.ui.control.Label
        Sim6Lamp                    matlab.ui.control.Lamp
        Sim6LampLabel               matlab.ui.control.Label
        sim6TSwitch                 matlab.ui.control.ToggleSwitch
        sim6TSwitchLabel            matlab.ui.control.Label
        sim6Switch                  matlab.ui.control.Switch
        sim6SwitchLabel             matlab.ui.control.Label
        Sim5Lamp                    matlab.ui.control.Lamp
        Sim5LampLabel               matlab.ui.control.Label
        sim5TSwitch                 matlab.ui.control.ToggleSwitch
        sim5TSwitchLabel            matlab.ui.control.Label
        sim5Switch                  matlab.ui.control.Switch
        sim5SwitchLabel             matlab.ui.control.Label
        Sim4Lamp                    matlab.ui.control.Lamp
        Sim4LampLabel               matlab.ui.control.Label
        sim4TSwitch                 matlab.ui.control.ToggleSwitch
        sim4TSwitchLabel            matlab.ui.control.Label
        sim4Switch                  matlab.ui.control.Switch
        sim4SwitchLabel             matlab.ui.control.Label
        Sim3Lamp                    matlab.ui.control.Lamp
        Sim3LampLabel               matlab.ui.control.Label
        sim3TSwitch                 matlab.ui.control.ToggleSwitch
        sim3TSwitchLabel            matlab.ui.control.Label
        sim3Switch                  matlab.ui.control.Switch
        sim3SwitchLabel             matlab.ui.control.Label
        Sim2Lamp                    matlab.ui.control.Lamp
        Sim2LampLabel               matlab.ui.control.Label
        sim2TSwitch                 matlab.ui.control.ToggleSwitch
        sim2TSwitchLabel            matlab.ui.control.Label
        sim2Switch                  matlab.ui.control.Switch
        sim2SwitchLabel             matlab.ui.control.Label
        Sim1Lamp                    matlab.ui.control.Lamp
        Sim1LampLabel               matlab.ui.control.Label
        sim1TSwitch                 matlab.ui.control.ToggleSwitch
        sim1TSwitchLabel            matlab.ui.control.Label
        sim1Switch                  matlab.ui.control.Switch
        sim1SwitchLabel             matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        ViewTab                     matlab.ui.container.Tab
        OperatingTimeTextArea       matlab.ui.control.TextArea
        OperatingTimeTextAreaLabel  matlab.ui.control.Label
        FinishLamp                  matlab.ui.control.Lamp
        FinishLampLabel             matlab.ui.control.Label
        TransportLamp               matlab.ui.control.Lamp
        TransportLampLabel          matlab.ui.control.Label
        CompleteLamp                matlab.ui.control.Lamp
        CompleteLampLabel           matlab.ui.control.Label
        HarvestLamp_2               matlab.ui.control.Lamp
        HarvestLamp_2Label          matlab.ui.control.Label
        ModeNumberTextArea          matlab.ui.control.TextArea
        ModeStateTextArea           matlab.ui.control.TextArea
        EEStateTextArea             matlab.ui.control.TextArea
        ArmStateTextArea            matlab.ui.control.TextArea
        ArmStateTextAreaLabel       matlab.ui.control.Label
        EEStateTextAreaLabel        matlab.ui.control.Label
        ModeStateTextAreaLabel      matlab.ui.control.Label
        AnalysisLamp_2              matlab.ui.control.Lamp
        AnalysisLamp_2Label         matlab.ui.control.Label
        SearchLamp                  matlab.ui.control.Lamp
        SearchLampLabel             matlab.ui.control.Label
        StopLamp                    matlab.ui.control.Lamp
        StopLampLabel               matlab.ui.control.Label
        ModeNumberTextAreaLabel     matlab.ui.control.Label
        ParamTab                    matlab.ui.container.Tab
        GainSlider                  matlab.ui.control.Slider
        GainSliderLabel             matlab.ui.control.Label
        CheckTab                    matlab.ui.container.Tab
        MoreCarefullyLabel          matlab.ui.control.Label
        ConnectionCheckBox          matlab.ui.control.CheckBox
        EnvironmentCheckBox         matlab.ui.control.CheckBox
        MainCheckBox_2              matlab.ui.control.CheckBox
        NUCCheckBox                 matlab.ui.control.CheckBox
        KinectCheckBox              matlab.ui.control.CheckBox
        EECheckBox                  matlab.ui.control.CheckBox
        EDFCheckBox                 matlab.ui.control.CheckBox
        ArmCheckBox                 matlab.ui.control.CheckBox
        CartCheckBox                matlab.ui.control.CheckBox
        MainCheckBox                matlab.ui.control.CheckBox
        ONOFFLabel                  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        EmergencyButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Check;
        SimStart;
        str;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hObject = app.UIFigure;
            assignin('base','GUI_handle',hObject)
            app.Check = 0;
            app.SimStart = 0;
            app.str = {'Sim1_Supervisor_OA', 'Sim2_ImageProcessor_OA', 'Sim5_OrthogonalArm_XY_OA', ...
                        'Sim3_Cart_OA', 'Sim4_EndEffector_OA', 'Sim6_OrthogonalArm_Z_OA'}; 

        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            %value = app.GainSlider.Value;
            %set_param('sim1/Gain','Gain',num2str(value));
        end

        % Value changed function: ArmCheckBox, CartCheckBox, 
        % ConnectionCheckBox, EDFCheckBox, EECheckBox, 
        % EnvironmentCheckBox, KinectCheckBox, MainCheckBox, 
        % MainCheckBox_2, NUCCheckBox
        function CheckBoxValueChanged(app, event)
            % チェックボックスのON/OFFを全て確認
            HumanError = 0;
            cth = findobj(app.CheckTab,'Tag','CheckPoint'); %チェックボックスのタグを'CheckPoint'に統一
            for i = 1:10
                if cth(i).Value == 1
                    HumanError = HumanError + 1;
                end
            end
            
            % 全てにチェックがついているか確認
            if HumanError==10
                app.Check = 1;
                if app.SimStart == 1
                    app.RunButton.BackgroundColor = [0.47 0.67 0];
                    app.EmergencyButton.BackgroundColor = [0.8 0.8 0.8];
                    app.RunButton.Enable = 'on';
                    app.EmergencyButton.Enable = 'off';
                end
            else
                app.Check = 0;
                app.RunButton.BackgroundColor = [0.9 1 0.8];
                app.EmergencyButton.BackgroundColor = [1 0.8 0.8];
                app.RunButton.Enable = 'off';
                app.EmergencyButton.Enable = 'off';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            assignin('base','StartSW',1);
            %set_param('Sim1_Supervisor_OA/Main Controller/SW','Value', '1');
            app.RunButton.BackgroundColor = [0.9 1 0.8];
            app.EmergencyButton.BackgroundColor = [1 0 0];
            app.RunButton.Enable = 'off';
            app.EmergencyButton.Enable = 'on';
        end

        % Button pushed function: EmergencyButton
        function EmergencyButtonPushed(app, event)
            assignin('base','StartSW',0);
            %set_param('Sim1_Supervisor_OA/Main Controller/SW','Value', '0');
            app.RunButton.BackgroundColor = [0.47 0.67 0];
            app.EmergencyButton.BackgroundColor = [1 0.8 0.8];
            app.RunButton.Enable = 'on';
            app.EmergencyButton.Enable = 'off';
        end

        % Value changed function: sim1Switch
        function sim1SwitchValueChanged(app, event)
            % simulinkモデルを開くorセーブして閉じる
            
            if strcmp(app.sim1Switch.Value,'Open')
                open_system('Sim1_Supervisor_OA')
                app.sim1TSwitch.Enable = 'on';
                app.Sim1Lamp.Color = [1 1 0];
            else
                save_system('Sim1_Supervisor_OA')
                close_system('Sim1_Supervisor_OA')
                app.sim1TSwitch.Enable = 'off';
                app.Sim1Lamp.Color = [0.8 0.8 0.8];
            end      
        end

        % Value changed function: sim1TSwitch
        function sim1TSwitchValueChanged(app, event)
            % simulinkモデルの実行or実行停止
            % システム開始・停止ボタンの操作可能状態ON/OFF
            
            if strcmp(app.sim1TSwitch.Value,'Start')
                set_param('Sim1_Supervisor_OA', 'SimulationCommand', 'Start'); %モデルの状態をStartにして実行だけを行う
                app.sim1Switch.Enable = 'off';
                app.Sim1Lamp.Color = [0 1 0];
                app.SimStart = 1;
                if app.Check == 1
                    app.RunButton.BackgroundColor = [0.47 0.67 0];
                    app.EmergencyButton.BackgroundColor = [0.8 0.8 0.8];
                    app.RunButton.Enable = 'on';
                    app.EmergencyButton.Enable = 'off';
                end

            else
                set_param('Sim1_Supervisor_OA', 'SimulationCommand', 'Stop'); %モデルの状態をStopにして実行停止だけを行う
                app.sim1Switch.Enable = 'on';
                app.Sim1Lamp.Color = [1 1 0];
                app.SimStart = 0;
                
                app.RunButton.BackgroundColor = [0.9 1 0.8];
                app.EmergencyButton.BackgroundColor = [1 0.8 0.8];
                app.RunButton.Enable = 'off';
                app.EmergencyButton.Enable = 'off';
            end  
        end

        % Value changed function: sim2Switch
        function sim2SwitchValueChanged(app, event)
            % simulinkモデルを開くorセーブして閉じる
            
            if strcmp(app.sim2Switch.Value,'Open')
                open_system('Sim2_ImageProcessor_OA')
                app.sim2TSwitch.Enable = 'on';
                app.Sim2Lamp.Color = [1 1 0];
            else
                save_system('Sim2_ImageProcessor_OA')
                close_system('Sim2_ImageProcessor_OA')
                app.sim2TSwitch.Enable = 'off';
                app.Sim2Lamp.Color = [0.8 0.8 0.8];
            end    
        end

        % Value changed function: sim2TSwitch
        function sim2TSwitchValueChanged2(app, event)
            % simulinkモデルの実行or実行停止
            
            if strcmp(app.sim2TSwitch.Value,'Start')
                set_param('Sim2_ImageProcessor_OA', 'SimulationCommand', 'Start'); %モデルの状態をStartにして実行だけを行う
                app.sim2Switch.Enable = 'off';
                app.Sim2Lamp.Color = [0 1 0];
            else
                set_param('Sim2_ImageProcessor_OA', 'SimulationCommand', 'Stop'); %モデルの状態をStopにして実行停止だけを行う
                app.sim2Switch.Enable = 'on';
                app.Sim2Lamp.Color = [1 1 0];
            end  
        end

        % Value changed function: sim3Switch
        function sim3SwitchValueChanged(app, event)
            % simulinkモデルを開くorセーブして閉じる
            
            if strcmp(app.sim3Switch.Value,'Open')
                open_system('Sim3_Cart_OA')
                app.sim3TSwitch.Enable = 'on';
                app.Sim3Lamp.Color = [1 1 0];
            else
                save_system('Sim3_Cart_OA')
                close_system('Sim3_Cart_OA')
                app.sim3TSwitch.Enable = 'off';
                app.Sim3Lamp.Color = [0.8 0.8 0.8];
            end    
        end

        % Value changed function: sim3TSwitch
        function sim3TSwitchValueChanged(app, event)
            % simulinkモデルの実行or実行停止
            
            if strcmp(app.sim3TSwitch.Value,'Start')
                %set_param('Sim3_Cart_OA', 'SimulationCommand', 'Start'); %モデルの状態をStartにして実行だけを行う
                %set_param(app.str{4}, 'SimulationCommand', 'Start');
                app.sim3Switch.Enable = 'off';
                app.Sim3Lamp.Color = [0 1 0];
                parfor j=4:6
                    set_param(app.str{j}, 'SimulationCommand', 'Start'); %モデルの状態をStartにして実行だけを行う
                end
            else
                %set_param('Sim3_Cart_OA', 'SimulationCommand', 'Stop'); %モデルの状態をStopにして実行停止だけを行う
                %set_param(app.str{4}, 'SimulationCommand', 'Stop');
                app.sim3Switch.Enable = 'on';
                app.Sim3Lamp.Color = [1 1 0];
                parfor j=4:6
                    set_param(app.str{j}, 'SimulationCommand', 'Stop'); %モデルの状態をStartにして実行だけを行う
                end
            end  
        end

        % Value changed function: sim4Switch
        function sim4SwitchValueChanged(app, event)
            % simulinkモデルを開くorセーブして閉じる
            
            if strcmp(app.sim4Switch.Value,'Open')
                open_system('Sim4_EndEffector_OA')
                app.sim4TSwitch.Enable = 'on';
                app.Sim4Lamp.Color = [1 1 0];
            else
                save_system('Sim4_EndEffector_OA')
                close_system('Sim4_EndEffector_OA')
                app.sim4TSwitch.Enable = 'off';
                app.Sim4Lamp.Color = [0.8 0.8 0.8];
            end    
        end

        % Value changed function: sim4TSwitch
        function sim4TSwitchValueChanged(app, event)
            % simulinkモデルの実行or実行停止
            
            if strcmp(app.sim4TSwitch.Value,'Start')
                set_param('Sim4_EndEffector_OA', 'SimulationCommand', 'Start'); %モデルの状態をStartにして実行だけを行う
                app.sim4Switch.Enable = 'off';
                app.Sim4Lamp.Color = [0 1 0];
            else
                set_param('Sim4_EndEffector_OA', 'SimulationCommand', 'Stop'); %モデルの状態をStopにして実行停止だけを行う
                app.sim4Switch.Enable = 'on';
                app.Sim4Lamp.Color = [1 1 0];
            end  
        end

        % Value changed function: sim5Switch
        function sim5SwitchValueChanged(app, event)
            % simulinkモデルを開くorセーブして閉じる
            
            if strcmp(app.sim5Switch.Value,'Open')
                open_system('Sim5_OrthogonalArm_XY_OA')
                app.sim5TSwitch.Enable = 'on';
                app.Sim5Lamp.Color = [1 1 0];
            else
                save_system('Sim5_OrthogonalArm_XY_OA')
                close_system('Sim5_OrthogonalArm_XY_OA')
                app.sim5TSwitch.Enable = 'off';
                app.Sim5Lamp.Color = [0.8 0.8 0.8];
            end    
        end

        % Value changed function: sim5TSwitch
        function sim5TSwitchValueChanged(app, event)
            % simulinkモデルの実行or実行停止
            
            if strcmp(app.sim5TSwitch.Value,'Start')
                set_param('Sim5_OrthogonalArm_XY_OA', 'SimulationCommand', 'Start'); %モデルの状態をStartにして実行だけを行う
                app.sim5Switch.Enable = 'off';
                app.Sim5Lamp.Color = [0 1 0];
            else
                set_param('Sim5_OrthogonalArm_XY_OA', 'SimulationCommand', 'Stop'); %モデルの状態をStopにして実行停止だけを行う
                app.sim5Switch.Enable = 'on';
                app.Sim5Lamp.Color = [1 1 0];
            end  
        end

        % Value changed function: sim6Switch
        function sim6SwitchValueChanged(app, event)
            % simulinkモデルを開くorセーブして閉じる
            
            if strcmp(app.sim6Switch.Value,'Open')
                open_system('Sim6_OrthogonalArm_Z_OA')
                app.sim6TSwitch.Enable = 'on';
                app.Sim6Lamp.Color = [1 1 0];
            else
                save_system('Sim6_OrthogonalArm_Z_OA')
                close_system('Sim6_OrthogonalArm_Z_OA')
                app.sim6TSwitch.Enable = 'off';
                app.Sim6Lamp.Color = [0.8 0.8 0.8];
            end    
        end

        % Value changed function: sim6TSwitch
        function sim6TSwitchValueChanged(app, event)
            % simulinkモデルの実行or実行停止
            
            if strcmp(app.sim6TSwitch.Value,'Start')
                set_param('Sim6_OrthogonalArm_Z_OA', 'SimulationCommand', 'Start'); %モデルの状態をStartにして実行だけを行う
                app.sim6Switch.Enable = 'off';
                app.Sim6Lamp.Color = [0 1 0];
            else
                set_param('Sim6_OrthogonalArm_Z_OA', 'SimulationCommand', 'Stop'); %モデルの状態をStopにして実行停止だけを行う
                app.sim6Switch.Enable = 'on';
                app.Sim6Lamp.Color = [1 1 0];
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmergencyButton = uibutton(app.UIFigure, 'push');
            app.EmergencyButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyButtonPushed, true);
            app.EmergencyButton.Enable = 'off';
            app.EmergencyButton.Text = 'Emergency';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.ModeNumberTextAreaLabel = uilabel(app.ViewTab);
            app.ModeNumberTextAreaLabel.Text = 'Mode Number';
            app.StopLampLabel = uilabel(app.ViewTab);
            app.StopLampLabel.Text = 'Stop';
            app.StopLamp = uilamp(app.ViewTab);
            app.StopLamp.Tag = 'state0';
            app.SearchLampLabel = uilabel(app.ViewTab);
            app.SearchLampLabel.Text = 'Search';
            app.SearchLamp = uilamp(app.ViewTab);
            app.SearchLamp.Tag = 'state1';
            app.AnalysisLamp_2Label = uilabel(app.ViewTab);
            app.AnalysisLamp_2Label.Text = 'Analysis';
            app.AnalysisLamp_2 = uilamp(app.ViewTab);
            app.AnalysisLamp_2.Tag = 'state2';
            app.ModeStateTextAreaLabel = uilabel(app.ViewTab);
            app.ModeStateTextAreaLabel.Text = 'Mode State';
            app.EEStateTextAreaLabel = uilabel(app.ViewTab);
            app.EEStateTextAreaLabel.Text = {'EE State'; ''};
            app.ArmStateTextAreaLabel = uilabel(app.ViewTab);
            app.ArmStateTextAreaLabel.Text = 'Arm State';
            app.ArmStateTextArea = uitextarea(app.ViewTab);
            app.ArmStateTextArea.Tag = 'ArmState';
            app.ArmStateTextArea.Editable = 'off';
            app.EEStateTextArea = uitextarea(app.ViewTab);
            app.EEStateTextArea.Tag = 'EEState';
            app.EEStateTextArea.Editable = 'off';
            app.ModeStateTextArea = uitextarea(app.ViewTab);
            app.ModeStateTextArea.Tag = 'State';
            app.ModeStateTextArea.Editable = 'off';
            app.ModeNumberTextArea = uitextarea(app.ViewTab);
            app.ModeNumberTextArea.Tag = 'ModeNum';
            app.ModeNumberTextArea.Editable = 'off';
            app.HarvestLamp_2Label = uilabel(app.ViewTab);
            app.HarvestLamp_2Label.Text = 'Harvest';
            app.HarvestLamp_2 = uilamp(app.ViewTab);
            app.HarvestLamp_2.Tag = 'state3';
            app.CompleteLampLabel = uilabel(app.ViewTab);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.ViewTab);
            app.CompleteLamp.Tag = 'state4';
            app.TransportLampLabel = uilabel(app.ViewTab);
            app.TransportLampLabel.Text = 'Transport';
            app.TransportLamp = uilamp(app.ViewTab);
            app.TransportLamp.Tag = 'state5';
            app.FinishLampLabel = uilabel(app.ViewTab);
            app.FinishLampLabel.Text = 'Finish';
            app.FinishLamp = uilamp(app.ViewTab);
            app.FinishLamp.Tag = 'state6';
            app.OperatingTimeTextAreaLabel = uilabel(app.ViewTab);
            app.OperatingTimeTextAreaLabel.Text = 'Operating Time';
            app.OperatingTimeTextArea = uitextarea(app.ViewTab);
            app.OperatingTimeTextArea.Tag = 'OperatingTime';
            app.OperatingTimeTextArea.Editable = 'off';
            app.ParamTab = uitab(app.TabGroup);
            app.ParamTab.Title = 'Param';
            app.GainSliderLabel = uilabel(app.ParamTab);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.ParamTab);
            app.GainSlider.Limits = [0 5];
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.Tag = 'gainparam';
            app.GainSlider.Value = 1;
            app.CheckTab = uitab(app.TabGroup);
            app.CheckTab.Title = 'Check';
            app.Label_3 = uilabel(app.CheckTab);
            app.Label_3.Text = '周囲の環境に問題がないかを確認する．';
            app.Label_2 = uilabel(app.CheckTab);
            app.Label_2.Text = '配線の接続に問題がないかを確認する．';
            app.Label = uilabel(app.CheckTab);
            app.Label.Text = '電力が十分であるかを確認する．';
            app.ONOFFLabel = uilabel(app.CheckTab);
            app.ONOFFLabel.Text = 'スイッチのON/OFF状態を確認する．';
            app.MainCheckBox = uicheckbox(app.CheckTab);
            app.MainCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.MainCheckBox.Tag = 'CheckPoint';
            app.MainCheckBox.Text = 'Main';
            app.CartCheckBox = uicheckbox(app.CheckTab);
            app.CartCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CartCheckBox.Tag = 'CheckPoint';
            app.CartCheckBox.Text = 'Cart';
            app.ArmCheckBox = uicheckbox(app.CheckTab);
            app.ArmCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.ArmCheckBox.Tag = 'CheckPoint';
            app.ArmCheckBox.Text = 'Arm';
            app.EDFCheckBox = uicheckbox(app.CheckTab);
            app.EDFCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.EDFCheckBox.Tag = 'CheckPoint';
            app.EDFCheckBox.Text = 'EDF';
            app.EECheckBox = uicheckbox(app.CheckTab);
            app.EECheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.EECheckBox.Tag = 'CheckPoint';
            app.EECheckBox.Text = 'EE';
            app.KinectCheckBox = uicheckbox(app.CheckTab);
            app.KinectCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.KinectCheckBox.Tag = 'CheckPoint';
            app.KinectCheckBox.Text = 'Kinect';
            app.NUCCheckBox = uicheckbox(app.CheckTab);
            app.NUCCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.NUCCheckBox.Tag = 'CheckPoint';
            app.NUCCheckBox.Text = 'NUC';
            app.MainCheckBox_2 = uicheckbox(app.CheckTab);
            app.MainCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.MainCheckBox_2.Tag = 'CheckPoint';
            app.MainCheckBox_2.Text = 'Main';
            app.EnvironmentCheckBox = uicheckbox(app.CheckTab);
            app.EnvironmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.EnvironmentCheckBox.Tag = 'CheckPoint';
            app.EnvironmentCheckBox.Text = 'Environment';
            app.ConnectionCheckBox = uicheckbox(app.CheckTab);
            app.ConnectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.ConnectionCheckBox.Tag = 'CheckPoint';
            app.ConnectionCheckBox.Text = 'Connection';
            app.MoreCarefullyLabel = uilabel(app.CheckTab);
            app.MoreCarefullyLabel.Text = 'More Carefully';
            app.sim1SwitchLabel = uilabel(app.UIFigure);
            app.sim1SwitchLabel.Visible = 'off';
            app.sim1SwitchLabel.Text = 'sim1';
            app.sim1Switch = uiswitch(app.UIFigure, 'slider');
            app.sim1Switch.Items = {'Close', 'Open'};
            app.sim1Switch.ValueChangedFcn = createCallbackFcn(app, @sim1SwitchValueChanged, true);
            app.sim1Switch.Tag = 'open_sim1';
            app.sim1Switch.Value = 'Close';
            app.sim1TSwitchLabel = uilabel(app.UIFigure);
            app.sim1TSwitchLabel.Enable = 'off';
            app.sim1TSwitchLabel.Visible = 'off';
            app.sim1TSwitchLabel.Text = 'sim1T';
            app.sim1TSwitch = uiswitch(app.UIFigure, 'toggle');
            app.sim1TSwitch.Items = {'Stop', 'Start'};
            app.sim1TSwitch.ValueChangedFcn = createCallbackFcn(app, @sim1TSwitchValueChanged, true);
            app.sim1TSwitch.Tag = 'start_sim1';
            app.sim1TSwitch.Enable = 'off';
            app.sim1TSwitch.Value = 'Stop';
            app.Sim1LampLabel = uilabel(app.UIFigure);
            app.Sim1LampLabel.Text = 'Sim1';
            app.Sim1Lamp = uilamp(app.UIFigure);
            app.Sim1Lamp.Tag = 'Lamp_sim1';
            app.sim2SwitchLabel = uilabel(app.UIFigure);
            app.sim2SwitchLabel.Visible = 'off';
            app.sim2SwitchLabel.Text = 'sim2';
            app.sim2Switch = uiswitch(app.UIFigure, 'slider');
            app.sim2Switch.Items = {'Close', 'Open'};
            app.sim2Switch.ValueChangedFcn = createCallbackFcn(app, @sim2SwitchValueChanged, true);
            app.sim2Switch.Tag = 'open_sim2';
            app.sim2Switch.Value = 'Close';
            app.sim2TSwitchLabel = uilabel(app.UIFigure);
            app.sim2TSwitchLabel.Enable = 'off';
            app.sim2TSwitchLabel.Visible = 'off';
            app.sim2TSwitchLabel.Text = 'sim2T';
            app.sim2TSwitch = uiswitch(app.UIFigure, 'toggle');
            app.sim2TSwitch.Items = {'Stop', 'Start'};
            app.sim2TSwitch.ValueChangedFcn = createCallbackFcn(app, @sim2TSwitchValueChanged2, true);
            app.sim2TSwitch.Tag = 'start_sim2';
            app.sim2TSwitch.Enable = 'off';
            app.sim2TSwitch.Value = 'Stop';
            app.Sim2LampLabel = uilabel(app.UIFigure);
            app.Sim2LampLabel.Text = 'Sim2';
            app.Sim2Lamp = uilamp(app.UIFigure);
            app.Sim2Lamp.Tag = 'Lamp_sim2';
            app.sim3SwitchLabel = uilabel(app.UIFigure);
            app.sim3SwitchLabel.Visible = 'off';
            app.sim3SwitchLabel.Text = 'sim3';
            app.sim3Switch = uiswitch(app.UIFigure, 'slider');
            app.sim3Switch.Items = {'Close', 'Open'};
            app.sim3Switch.ValueChangedFcn = createCallbackFcn(app, @sim3SwitchValueChanged, true);
            app.sim3Switch.Tag = 'open_sim3';
            app.sim3Switch.Value = 'Close';
            app.sim3TSwitchLabel = uilabel(app.UIFigure);
            app.sim3TSwitchLabel.Enable = 'off';
            app.sim3TSwitchLabel.Visible = 'off';
            app.sim3TSwitchLabel.Text = 'sim3T';
            app.sim3TSwitch = uiswitch(app.UIFigure, 'toggle');
            app.sim3TSwitch.Items = {'Stop', 'Start'};
            app.sim3TSwitch.ValueChangedFcn = createCallbackFcn(app, @sim3TSwitchValueChanged, true);
            app.sim3TSwitch.Tag = 'start_sim3';
            app.sim3TSwitch.Enable = 'off';
            app.sim3TSwitch.Value = 'Stop';
            app.Sim3LampLabel = uilabel(app.UIFigure);
            app.Sim3LampLabel.Text = 'Sim3';
            app.Sim3Lamp = uilamp(app.UIFigure);
            app.Sim3Lamp.Tag = 'Lamp_sim3';
            app.sim4SwitchLabel = uilabel(app.UIFigure);
            app.sim4SwitchLabel.Visible = 'off';
            app.sim4SwitchLabel.Text = 'sim4';
            app.sim4Switch = uiswitch(app.UIFigure, 'slider');
            app.sim4Switch.Items = {'Close', 'Open'};
            app.sim4Switch.ValueChangedFcn = createCallbackFcn(app, @sim4SwitchValueChanged, true);
            app.sim4Switch.Tag = 'open_sim4';
            app.sim4Switch.Value = 'Close';
            app.sim4TSwitchLabel = uilabel(app.UIFigure);
            app.sim4TSwitchLabel.Enable = 'off';
            app.sim4TSwitchLabel.Visible = 'off';
            app.sim4TSwitchLabel.Text = 'sim4T';
            app.sim4TSwitch = uiswitch(app.UIFigure, 'toggle');
            app.sim4TSwitch.Items = {'Stop', 'Start'};
            app.sim4TSwitch.ValueChangedFcn = createCallbackFcn(app, @sim4TSwitchValueChanged, true);
            app.sim4TSwitch.Tag = 'start_sim4';
            app.sim4TSwitch.Enable = 'off';
            app.sim4TSwitch.Value = 'Stop';
            app.Sim4LampLabel = uilabel(app.UIFigure);
            app.Sim4LampLabel.Text = 'Sim4';
            app.Sim4Lamp = uilamp(app.UIFigure);
            app.Sim4Lamp.Tag = 'Lamp_sim4';
            app.sim5SwitchLabel = uilabel(app.UIFigure);
            app.sim5SwitchLabel.Visible = 'off';
            app.sim5SwitchLabel.Text = 'sim5';
            app.sim5Switch = uiswitch(app.UIFigure, 'slider');
            app.sim5Switch.Items = {'Close', 'Open'};
            app.sim5Switch.ValueChangedFcn = createCallbackFcn(app, @sim5SwitchValueChanged, true);
            app.sim5Switch.Tag = 'open_sim5';
            app.sim5Switch.Value = 'Close';
            app.sim5TSwitchLabel = uilabel(app.UIFigure);
            app.sim5TSwitchLabel.Enable = 'off';
            app.sim5TSwitchLabel.Visible = 'off';
            app.sim5TSwitchLabel.Text = 'sim5T';
            app.sim5TSwitch = uiswitch(app.UIFigure, 'toggle');
            app.sim5TSwitch.Items = {'Stop', 'Start'};
            app.sim5TSwitch.ValueChangedFcn = createCallbackFcn(app, @sim5TSwitchValueChanged, true);
            app.sim5TSwitch.Tag = 'start_sim5';
            app.sim5TSwitch.Enable = 'off';
            app.sim5TSwitch.Value = 'Stop';
            app.Sim5LampLabel = uilabel(app.UIFigure);
            app.Sim5LampLabel.Text = 'Sim5';
            app.Sim5Lamp = uilamp(app.UIFigure);
            app.Sim5Lamp.Tag = 'Lamp_sim5';
            app.sim6SwitchLabel = uilabel(app.UIFigure);
            app.sim6SwitchLabel.Visible = 'off';
            app.sim6SwitchLabel.Text = 'sim6';
            app.sim6Switch = uiswitch(app.UIFigure, 'slider');
            app.sim6Switch.Items = {'Close', 'Open'};
            app.sim6Switch.ValueChangedFcn = createCallbackFcn(app, @sim6SwitchValueChanged, true);
            app.sim6Switch.Tag = 'open_sim6';
            app.sim6Switch.Value = 'Close';
            app.sim6TSwitchLabel = uilabel(app.UIFigure);
            app.sim6TSwitchLabel.Enable = 'off';
            app.sim6TSwitchLabel.Visible = 'off';
            app.sim6TSwitchLabel.Text = 'sim6T';
            app.sim6TSwitch = uiswitch(app.UIFigure, 'toggle');
            app.sim6TSwitch.Items = {'Stop', 'Start'};
            app.sim6TSwitch.ValueChangedFcn = createCallbackFcn(app, @sim6TSwitchValueChanged, true);
            app.sim6TSwitch.Tag = 'start_sim6';
            app.sim6TSwitch.Enable = 'off';
            app.sim6TSwitch.Value = 'Stop';
            app.Sim6LampLabel = uilabel(app.UIFigure);
            app.Sim6LampLabel.Text = 'Sim6';
            app.Sim6Lamp = uilamp(app.UIFigure);
            app.Sim6Lamp.Tag = 'Lamp_sim6';
            app.TomatoHarvestingRobotLabel = uilabel(app.UIFigure);
            app.TomatoHarvestingRobotLabel.Text = 'Tomato Harvesting Robot';
            app.ForOrthgonalArmLabel = uilabel(app.UIFigure);
            app.ForOrthgonalArmLabel.Text = '- For Orthgonal Arm -';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end